classdef ueGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        SimTab                    matlab.ui.container.Tab
        RenderButton              matlab.ui.control.Button
        SimSaveButton             matlab.ui.control.Button
        SimLoadButton             matlab.ui.control.Button
        SimViewButton             matlab.ui.control.Button
        SimModelEditField         matlab.ui.control.EditField
        SimModelEditFieldLabel    matlab.ui.control.Label
        SimViewTypeDropDown       matlab.ui.control.DropDown
        SimViewTypeDropDownLabel  matlab.ui.control.Label
        SimCamVEditField          matlab.ui.control.NumericEditField
        SimCamVEditFieldLabel     matlab.ui.control.Label
        SimCamHEditField          matlab.ui.control.NumericEditField
        SimCamHEditFieldLabel     matlab.ui.control.Label
        SimRearXEditField         matlab.ui.control.NumericEditField
        SimRearXEditFieldLabel    matlab.ui.control.Label
        SimCamZEditField          matlab.ui.control.NumericEditField
        SimCamZEditFieldLabel     matlab.ui.control.Label
        SimCamYEditField          matlab.ui.control.NumericEditField
        SimCamYEditFieldLabel     matlab.ui.control.Label
        SimCamXEditField          matlab.ui.control.NumericEditField
        SimCamXEditFieldLabel     matlab.ui.control.Label
        SimImgBrownsButton        matlab.ui.control.Button
        SimImgFileEditField       matlab.ui.control.EditField
        SimUseFileCheckBox        matlab.ui.control.CheckBox
        SimCsEditField            matlab.ui.control.NumericEditField
        SimCsEditFieldLabel       matlab.ui.control.Label
        SimCrEditField            matlab.ui.control.NumericEditField
        SimCrEditFieldLabel       matlab.ui.control.Label
        SimFlEditField            matlab.ui.control.NumericEditField
        SimFlEditFieldLabel       matlab.ui.control.Label
        SimFwEditField            matlab.ui.control.NumericEditField
        SimFwEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        mHandle
        mResult
        mImage
    end
    
    methods (Access = private)
        
        function handle = getHandle(app, sName)
            switch sName
                case 'Sim'
                    if isfield(app.mHandle, sName) && ishandle(app.mHandle.Sim)
                        handle = app.mHandle.Sim;
                        return
                    end
                otherwise
                    if isfield(app.mHandle, 'Fig') && ishandle(app.mHandle.Fig)
                        handle = app.mHandle.Fig;
                        return
                    end
            end
            handle = genHandle(app, sName);
        end
        function handle = genHandle(app, sName)
            switch sName
                case 'Sim'
                    handle = figure('Name','Sim', 'MenuBar', 'none', 'ToolBar', 'none');
                    app.mHandle.Sim = handle;
                otherwise
                    handle = figure('Name','Untitled');
                    app.mHandle.Fig = handle;
            end
        end
        function deleteHandle(app)
            if isfield(app.mHandle, 'Sim')
                delete(app.mHandle.Sim);
            end
            if isfield(app.mHandle, 'Fig')
                delete(app.mHandle.Fig);
            end
        end

        function saveParam(app,sName)
            pd = uiprogressdlg(app.UIFigure,...
                'Title', 'Save Parameters',...
                'Message', 'Saving...');
            param = getParam(app,sName);
            switch sName
                case 'Sim'
                    switch param.vc
                        case 0
                            vt = 'C1';
                        case 1
                            vt = 'C2_DRV';
                        case 3
                            vt = 'C3_DRV';
                        case 5
                            vt = 'C4_DRV';
                        case 2
                            vt = 'C2_PAX';
                        case 4
                            vt = 'C3_PAX';
                        otherwise
                            vt = 'C4_PAX';
                    end
                    fn = ['SIM_' param.mn '_' vt];
            end
            defname = ['CMSEK_' fn '.mat'];
            [filename, pathname] = uiputfile({'*.mat', 'MAT (*.mat)'; '*.*', 'All Files (*.*)'}, 'Save Parameters', defname);
            figure(app.UIFigure);
            if filename
                save([pathname, filename], 'param', '-mat');
            end
            close(pd);
        end
        function loadParam(app,sName)
            pd = uiprogressdlg(app.UIFigure,...
                'Title', 'Load Parameters',...
                'Message', 'Loading...');
            [filename, pathname] = uigetfile({'*.mat', 'MAT (*.mat)'; '*.*', 'All Files (*.*)'}, 'Select File', 'param.mat', 'MultiSelect', 'off');
            figure(app.UIFigure);
            if filename
                if exist([pathname, filename], 'file')
                    load([pathname, filename], '-mat', 'param');
                    setParam(app,sName,param)
                end
            end
            close(pd);
        end
        function param = getParam(app,sName)
            switch sName
                case 'Sim'
                    param = getSimParam(app);
                otherwise % 'All'
                    param.Sim = getSimParam(app);
            end
        end
        function setParam(app,sName,param)
            switch sName
                case 'Sim'
                    invalidateImage(app,sName);
                    invalidateResult(app,sName);
                    setSimParam(app,param);
                otherwise % 'All'
                    invalidateImage(app,'All');
                    invalidateResult(app,'All');
                    if isfield(param, 'Sim')
                        setSimParam(app,param.Sim);
                    end
            end
        end
        function invalidateResult(app, sName)
            if isfield(app.mResult, sName)
                switch sName
                    case 'Sim'
                        app.mResult = rmfield(app.mResult, sName);
                        invalidateResult(app, 'Sns');
                    case 'All'
                        invalidateResult(app,'Sim');
                end
            end
        end
        function result = getResult(app,sName)
            if isfield(app.mResult, sName)
                switch sName
                    case 'Sim'
                        result = app.mResult.Sim;
                end
            else
                switch sName
                    case 'Sim'
                        result = genResult(app,sName);
                    otherwise % 'All'
                        result.Sim = getResult(app,'Sim');
                end
                setResult(app,sName);
            end
        end
        function result = genResult(app, sName)
            switch sName
                case 'Sim'
                    result = genSimResult(app);
                    app.mResult.Sim = result;
                otherwise % 'All'
                    result.Sim = genResult(app,'Sim');
                    app.mResult = result;
            end
        end
        function setResult(app,sName)
        end
        function invalidateImage(app,sName)
            if isfield(app.mImage, sName)
                switch sName
                    case 'Sim'
                        app.mImage = rmfield(app.mImage, sName);
                        invalidateImage(app, 'Sns');
                    case 'All'
                        invalidateImage(app,'Sim');
                end
            end
        end
        function img = getImage(app,sName)
            if isfield(app.mImage, sName)
                switch sName
                    case 'Sim'
                        img = app.mImage.Sim;
                    otherwise % 'All'
                        img.Sim = getImage(app,'Sim');
                end
            else
                switch sName
                    case 'Sim'
                        img = genImage(app,sName);
                    otherwise % 'All'
                        img.Sim = getImage(app,'Sim');
                end
            end
        end
        function img = genImage(app,sName)
            switch sName
                case 'Sim'
                    img = genSimImg(app);
                    app.mImage.Sim = img;
                otherwise % 'All'
                    img.Sim = genImage(app,'Sim');
                    app.mImage = img;
            end
        end
%========================================================================== get/setParam
        function param = getSimParam(app)
            param.fw = app.SimFwEditField.Value;
            param.fl = app.SimFlEditField.Value;
            param.cr = app.SimCrEditField.Value;
            param.cs = app.SimCsEditField.Value;
            param.uf = app.SimUseFileCheckBox.Value;
            param.fn = app.SimImgFileEditField.Value;
            param.cx = app.SimCamXEditField.Value;
            param.cy = app.SimCamYEditField.Value;
            param.cz = app.SimCamZEditField.Value;
            param.rx = app.SimRearXEditField.Value;
            param.ch = app.SimCamHEditField.Value;
            param.cv = app.SimCamVEditField.Value;
            param.vc = app.SimViewTypeDropDown.Value;
            param.mn = app.SimModelEditField.Value;
        end
        function setSimParam(app,param)
            if isfield(param,'fw')
                app.SimFwEditField.Value = param.fw;
            end
            if isfield(param,'fl')
                app.SimFlEditField.Value = param.fl;
            end
            if isfield(param,'cr')
                app.SimCrEditField.Value = param.cr;
            end
            if isfield(param,'cs')
                app.SimCsEditField.Value = param.cs;
            end
            if isfield(param,'uf')
                app.SimUseFileCheckBox.Value = param.uf;
                if param.uf
                    app.SimCrEditField.Editable = 0;
                    app.SimCsEditField.Editable = 0;
                    app.SimImgFileEditField.Enable = 1;
                    app.SimImgBrownsButton.Enable = 1;
                else
                    app.SimCrEditField.Editable = 1;
                    app.SimCsEditField.Editable = 1;
                    app.SimImgFileEditField.Enable = 0;
                    app.SimImgBrownsButton.Enable = 0;
                end
            end
            if isfield(param,'fn')
                app.SimImgFileEditField.Value = param.fn;
            end
            if isfield(param,'cx')
                app.SimCamXEditField.Value = param.cx;
            end
            if isfield(param,'cy')
                app.SimCamYEditField.Value = param.cy;
            end
            if isfield(param,'cz')
                app.SimCamZEditField.Value = param.cz;
            end
            if isfield(param,'rx')
                app.SimRearXEditField.Value = param.rx;
            end
            if isfield(param,'ch')
                app.SimCamHEditField.Value = param.ch;
            end
            if isfield(param,'cv')
                app.SimCamVEditField.Value = param.cv;
            end
            if isfield(param,'vc')
                app.SimViewTypeDropDown.Value = param.vc;
            end
            if isfield(param,'mn')
                app.SimModelEditField.Value = param.mn;
            end
        end
%========================================================================== genResult
        function result = genSimResult(app)
            switch app.SimViewTypeDropDown.Value
                case 0 % Class I
                    result.V_eye_min = 0.7;
                    result.M_mirror_min = 0.31;
                    result.M_mirror_avg = 0.33;
                    result.M_system_aspect_min = -0.34;
                    result.M_system_aspect_max = 0.25;
                case 1 % Class II Driver
                    result.V_eye_min = 0.8;
                    result.M_mirror_min = 0.26;
                    result.M_mirror_avg = 0.31;
                    result.M_system_aspect_min = -0.42;
                    result.M_system_aspect_max = 0.3;
                case 3 % Class III Driver
                    result.V_eye_min = 0.7;
                    result.M_mirror_min = 0.29;
                    result.M_mirror_avg = 0.31;
                    result.M_system_aspect_min = -0.34;
                    result.M_system_aspect_max = 0.25;
                case 5 % Class IV Driver
                    result.V_eye_min = 0.8;
                    result.M_mirror_min = 0.054;
                    result.M_mirror_avg = 0.091;
                    result.M_system_aspect_min = -7;
                    result.M_system_aspect_max = 7;
                case 2 % Class II Passenger
                    result.V_eye_min = 0.8;
                    result.M_mirror_min = 0.13;
                    result.M_mirror_avg = 0.16;
                    result.M_system_aspect_min = -0.42;
                    result.M_system_aspect_max = 0.3;
                case 4 % Class III Passenger
                    result.V_eye_min = 0.7;
                    result.M_mirror_min = 0.19;
                    result.M_mirror_avg = 0.20;
                    result.M_system_aspect_min = -0.34;
                    result.M_system_aspect_max = 0.25;
                otherwise % Class IV Passenger
                    result.V_eye_min = 0.8;
                    result.M_mirror_min = 0.016;
                    result.M_mirror_avg = 0.046;
                    result.M_system_aspect_min = -3.4;
                    result.M_system_aspect_max = 2.5;
            end
            result.D_percent_max = 20;

            fl_mm = app.SimFlEditField.Value;
            rx_mm = app.SimRearXEditField.Value;
            x_mm = app.SimCamXEditField.Value;
            y_mm = app.SimCamYEditField.Value;
            z_mm = app.SimCamZEditField.Value;
            h_deg = app.SimCamHEditField.Value;
            v_deg = app.SimCamVEditField.Value;
            vclass = app.SimViewTypeDropDown.Value;
            [inf_x,inf_y,min_x,min_y,ext_x,ext_y,min_px,min_py,ext_px,ext_py] = cmsekMark(fl_mm, rx_mm, x_mm, y_mm, z_mm, h_deg, v_deg, vclass);
            result.InfX =-inf_x;
            result.InfY = inf_y;
            result.MinX =-min_x;
            result.MinY = min_y;
            result.ExtX =-ext_x;
            result.ExtY = ext_y;
            result.MinPx =-min_px;
            result.MinPy = min_py;
            result.ExtPx =-ext_px;
            result.ExtPy = ext_py;
        end
%========================================================================== setResult
%========================================================================== genImage
        function img = genSimImg(app)
            pd = uiprogressdlg(app.UIFigure,...
                'Title', 'Simulation Image',...
                'Message', 'Generating...',...
                "ShowPercentage","off");
            if app.SimUseFileCheckBox.Value
                fn = app.SimImgFileEditField.Value;
                if exist(fn, 'file')
                    img = double(imread(fn))./255;
                    img = img(:,end:-1:1,:);
                else
                    img = checkerGen(app.SimCrEditField.Value, app.SimCsEditField.Value);
                end
            else
                img = checkerGen(app.SimCrEditField.Value, app.SimCsEditField.Value);
            end
            close(pd);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'iWaySense CMS Evaluation Kit - GB15084';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            deleteHandle(app);
            delete(app)            
        end

        % Value changed function: SimUseFileCheckBox
        function SimUseFileCheckBoxValueChanged(app, event)
            if app.SimUseFileCheckBox.Value
                app.SimCrEditField.Editable = "off";
                app.SimCsEditField.Editable = "off";
                app.SimImgFileEditField.Enable = "on";
                app.SimImgBrownsButton.Enable = "on";
            else
                app.SimCrEditField.Editable = "on";
                app.SimCsEditField.Editable = "on";
                app.SimImgFileEditField.Enable = "off";
                app.SimImgBrownsButton.Enable = "off";
            end
            invalidateImage(app,'Sim');
        end

        % Button pushed function: SimImgBrownsButton
        function SimImgBrownsButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.png', 'PNG (*.png)'; '*.*', 'All Files (*.*)'}, 'Select an image file', 'MultiSelect', 'off');
            figure(app.UIFigure);
            if filename
                fullfilename = [pathname, filename];
                if exist(fullfilename,'file')
                    app.SimImgFileEditField.Value = fullfilename;
                    invalidateImage(app,'Sim');
                end
            end
        end

        % Button pushed function: SimViewButton
        function SimViewButtonPushed(app, event)
            invalidateImage(app,'Sim');
            invalidateResult(app,'Sim');
            
            img = getImage(app,'Sim');

            result = getResult(app,'Sim');
            sim_fw_mm = app.SimFwEditField.Value;
            img_hsize = size(img,2);
            img_vsize = size(img,1);
            inf_x  = img_hsize/2+0.5+result.InfX .*img_hsize./sim_fw_mm;
            inf_y  = img_vsize/2+0.5+result.InfY .*img_hsize./sim_fw_mm;
            min_px = img_hsize/2+0.5+result.MinPx.*img_hsize./sim_fw_mm;
            min_py = img_vsize/2+0.5+result.MinPy.*img_hsize./sim_fw_mm;
            ext_px = img_hsize/2+0.5+result.ExtPx.*img_hsize./sim_fw_mm;
            ext_py = img_vsize/2+0.5+result.ExtPy.*img_hsize./sim_fw_mm;
            handle = getHandle(app, 'Sim');
            figure(handle);
            ax = axes(handle);
            imshow(img,'Parent',ax,'Interpolation','bilinear');
            drawpoint(ax,"Color","r","InteractionsAllowed","none","LineWidth",0.1,"Position", [inf_x inf_y]);
            drawpolygon(ax,"Color","y","InteractionsAllowed","none","LineWidth",0.1,"Position", [min_px min_py]);
            drawpolyline(ax,"Color","b","InteractionsAllowed","none","LineWidth",0.1,"Position", [ext_px ext_py]);
        end

        % Button pushed function: SimSaveButton
        function SimSaveButtonPushed(app, event)
            saveParam(app,'Sim');
        end

        % Button pushed function: SimLoadButton
        function SimLoadButtonPushed(app, event)
            loadParam(app,'Sim');
        end

        % Callback function: RenderButton, SimFwEditField
        function RenderButtonPushed(app, event)
            %global imagesize CameraOPCenter CameraPosi CameraAngs CameraF;
            %global imagesizeP CameraOPCenterP CameraPosiP CameraAngsP CameraF_P,WheelBase;
            %saveParam(app,'Sim');
            %pars=load('CMSEK_SIM_F30_C3_DRV.mat');
            param = getParam(app,'Sim');
            %这里就是参数读取
            GetUESenseImg([param.cx,param.cy,param.cz],[param.ch,param.cv],[2048*param.fl/param.fw,2048*param.fl/param.fw],[2048,2048],param.rx,0.0);
            %GetUESenseImg([pars.param.cx,pars.param.cy,pars.param.cz],[pars.param.ch,pars.param.cv],[2048*pars.param.fl/pars.param.fw,2048*pars.param.fl/pars.param.fw],[2048,2048]);
        end

        % Value changed function: SimCrEditField
        function SimCrEditFieldValueChanged(app, event)
            value = app.SimCrEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimTab = uitab(app.TabGroup);
            app.SimTab.Title = 'Vehicle';
            app.SimFwEditFieldLabel = uilabel(app.SimTab);
            app.SimFwEditFieldLabel.Text = 'VCam Frame Width (mm)';
            app.SimFwEditField = uieditfield(app.SimTab, 'numeric');
            app.SimFwEditField.Limits = [1 72];
            app.SimFwEditField.ValueChangedFcn = createCallbackFcn(app, @RenderButtonPushed, true);
            app.SimFwEditField.Tag = 'edit1';
            app.SimFwEditField.Value = 36;
            app.SimFlEditFieldLabel = uilabel(app.SimTab);
            app.SimFlEditFieldLabel.Text = 'VCam Focal Length (mm)';
            app.SimFlEditField = uieditfield(app.SimTab, 'numeric');
            app.SimFlEditField.Limits = [1 72];
            app.SimFlEditField.Value = 10;
            app.SimCrEditFieldLabel = uilabel(app.SimTab);
            app.SimCrEditFieldLabel.Text = 'Checker Radius (pix)';
            app.SimCrEditField = uieditfield(app.SimTab, 'numeric');
            app.SimCrEditField.Limits = [16 2048];
            app.SimCrEditField.RoundFractionalValues = 'on';
            app.SimCrEditField.ValueDisplayFormat = '%.0f';
            app.SimCrEditField.ValueChangedFcn = createCallbackFcn(app, @SimCrEditFieldValueChanged, true);
            app.SimCrEditField.Value = 1024;
            app.SimCsEditFieldLabel = uilabel(app.SimTab);
            app.SimCsEditFieldLabel.Text = 'Checker Size (pix)';
            app.SimCsEditField = uieditfield(app.SimTab, 'numeric');
            app.SimCsEditField.Limits = [1 512];
            app.SimCsEditField.RoundFractionalValues = 'on';
            app.SimCsEditField.ValueDisplayFormat = '%.0f';
            app.SimCsEditField.Value = 100;
            app.SimUseFileCheckBox = uicheckbox(app.SimTab);
            app.SimUseFileCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimUseFileCheckBoxValueChanged, true);
            app.SimUseFileCheckBox.Text = 'Rendered Image File';
            app.SimImgFileEditField = uieditfield(app.SimTab, 'text');
            app.SimImgFileEditField.Enable = 'off';
            app.SimImgFileEditField.Value = 'F30_DRV.png';
            app.SimImgBrownsButton = uibutton(app.SimTab, 'push');
            app.SimImgBrownsButton.ButtonPushedFcn = createCallbackFcn(app, @SimImgBrownsButtonPushed, true);
            app.SimImgBrownsButton.Enable = 'off';
            app.SimImgBrownsButton.Text = '...';
            app.SimCamXEditFieldLabel = uilabel(app.SimTab);
            app.SimCamXEditFieldLabel.Text = 'VCam Offset X (mm)';
            app.SimCamXEditField = uieditfield(app.SimTab, 'numeric');
            app.SimCamXEditField.Limits = [-15000 3000];
            app.SimCamXEditField.Value = 599.5;
            app.SimCamYEditFieldLabel = uilabel(app.SimTab);
            app.SimCamYEditFieldLabel.Text = 'VCam Offset Y (mm)';
            app.SimCamYEditField = uieditfield(app.SimTab, 'numeric');
            app.SimCamYEditField.Limits = [-300 300];
            app.SimCamYEditField.Value = -47.86;
            app.SimCamZEditFieldLabel = uilabel(app.SimTab);
            app.SimCamZEditFieldLabel.Text = 'VCam Offset Z (mm)';
            app.SimCamZEditField = uieditfield(app.SimTab, 'numeric');
            app.SimCamZEditField.Limits = [0 3000];
            app.SimCamZEditField.Value = 1107.93;
            app.SimRearXEditFieldLabel = uilabel(app.SimTab);
            app.SimRearXEditFieldLabel.Text = 'Rear Wheel Offset X (mm)';
            app.SimRearXEditField = uieditfield(app.SimTab, 'numeric');
            app.SimRearXEditField.Limits = [0 15000];
            app.SimRearXEditField.Value = 1491.763;
            app.SimCamHEditFieldLabel = uilabel(app.SimTab);
            app.SimCamHEditFieldLabel.Text = 'VCam Tilt H (deg)';
            app.SimCamHEditField = uieditfield(app.SimTab, 'numeric');
            app.SimCamHEditField.Limits = [-45 45];
            app.SimCamHEditField.Value = 16.96;
            app.SimCamVEditFieldLabel = uilabel(app.SimTab);
            app.SimCamVEditFieldLabel.Text = 'VCam Tilt V (deg)';
            app.SimCamVEditField = uieditfield(app.SimTab, 'numeric');
            app.SimCamVEditField.Limits = [-45 89];
            app.SimCamVEditField.Value = 3.71;
            app.SimViewTypeDropDownLabel = uilabel(app.SimTab);
            app.SimViewTypeDropDownLabel.Text = 'View Class';
            app.SimViewTypeDropDown = uidropdown(app.SimTab);
            app.SimViewTypeDropDown.Items = {'I', 'II DRV', 'III DRV', 'IV DRV', 'II PAX', 'III PAX', 'IV PAX'};
            app.SimViewTypeDropDown.ItemsData = [0 1 3 5 2 4 6];
            app.SimViewTypeDropDown.Value = 3;
            app.SimModelEditFieldLabel = uilabel(app.SimTab);
            app.SimModelEditFieldLabel.Text = 'Model';
            app.SimModelEditField = uieditfield(app.SimTab, 'text');
            app.SimModelEditField.Value = 'F30';
            app.SimViewButton = uibutton(app.SimTab, 'push');
            app.SimViewButton.ButtonPushedFcn = createCallbackFcn(app, @SimViewButtonPushed, true);
            app.SimViewButton.Text = 'View';
            app.SimLoadButton = uibutton(app.SimTab, 'push');
            app.SimLoadButton.ButtonPushedFcn = createCallbackFcn(app, @SimLoadButtonPushed, true);
            app.SimLoadButton.Text = 'Load...';
            app.SimSaveButton = uibutton(app.SimTab, 'push');
            app.SimSaveButton.ButtonPushedFcn = createCallbackFcn(app, @SimSaveButtonPushed, true);
            app.SimSaveButton.Text = 'Save...';
            app.RenderButton = uibutton(app.SimTab, 'push');
            app.RenderButton.ButtonPushedFcn = createCallbackFcn(app, @RenderButtonPushed, true);
            app.RenderButton.Text = 'Render';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ueGUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end